classdef GroundedApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ExportasCSVButton    matlab.ui.control.Button
        ResetButton          matlab.ui.control.Button
        GatherandPlotButton  matlab.ui.control.Button
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        voltage
        temp
        humidity
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GatherandPlotButton
        function GatherandPlotButtonPushed(app, event)
            PSoC = [];
            priorPorts = instrfind;
            delete(priorPorts);
            PSoC = serialport("COM6", 115200, "DataBits", 8, "StopBits", 1);

            %for a working sensor
            app.temp = 0;
            app.humidity = 0;
            app.voltage = 0;
            while (app.voltage < 1)
                if (app.temp < 1)
                    app.temp = read(PSoC, 64, "uint8");
                elseif (app.humidity < 1)
                    app.humidity = read(PSoC, 64, "uint8");
                elseif (app.voltage < 1)
                    app.voltage = read(PSoC, 64, "uint16");
                end    
            end
         
            plot(app.UIAxes, app.voltage);
            plot(app.UIAxes2, app.humidity);
            plot(app.UIAxes3, app.temp);
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.temp = 0;
            app.humidity = 0;
            app.voltage = 0;
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
        end

        % Button pushed function: ExportasCSVButton
        function ExportasCSVButtonPushed(app, event)
            voltageC = app.voltage';
            tempC = app.temp';
            humidityC = app.humidity';
            combinedData = [voltageC, tempC, humidityC];
            writematrix(combinedData, 'output.csv')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Voltage vs Time')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Voltage(mV)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Humidity vs Time')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Humidity(RH%)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Temperature vs  Time')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Temperature(F)')
            zlabel(app.UIAxes3, 'Z')
            app.GatherandPlotButton = uibutton(app.UIFigure, 'push');
            app.GatherandPlotButton.ButtonPushedFcn = createCallbackFcn(app, @GatherandPlotButtonPushed, true);
            app.GatherandPlotButton.Text = 'Gather and Plot';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = {'Reset'; ''};
            app.ExportasCSVButton = uibutton(app.UIFigure, 'push');
            app.ExportasCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasCSVButtonPushed, true);
            app.ExportasCSVButton.Text = 'Export as CSV';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GroundedApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end